classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        ListBox                       matlab.ui.control.ListBox
        ListBoxLabel                  matlab.ui.control.Label
        VehicleTypeListBox            matlab.ui.control.ListBox
        VehicleTypeListBoxLabel       matlab.ui.control.Label
        AnalyzeButton                 matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        DetectedStateEditField        matlab.ui.control.EditField
        DetectedStateEditFieldLabel   matlab.ui.control.Label
        RecognizedPlateTextEditField  matlab.ui.control.EditField
        RecognizedPlateTextEditFieldLabel  matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        ContextMenu                   matlab.ui.container.ContextMenu
        Menu                          matlab.ui.container.Menu
        Menu2                         matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        ImageList
    end
    
    methods (Access = private)
        
        function analyzeImage(app, imagePath)
    % Step 1: Load and display the original image
    originalImg = imread(imagePath);

    % Prepare a single figure window
    figure('Name','License Plate Detection Steps','NumberTitle','off');
    subplot(3,3,1), imshow(originalImg), title('Original Image');

    % Step 2: Convert to grayscale
    grayImg = rgb2gray(originalImg);
    subplot(3,3,2), imshow(grayImg), title('Grayscale Image');

    % Step 3: Enhance contrast
    grayImg = imadjust(grayImg);
    subplot(3,3,3), imshow(grayImg), title('Contrast Enhanced');

    % Step 4: Edge detection using Canny
    edges = edge(grayImg, 'Canny', [0.1 0.3]);
    subplot(3,3,4), imshow(edges), title('Canny Edges');

    % Step 5: Morphological closing
    se = strel('rectangle', [3, 15]);
    closedImg = imclose(edges, se);
    subplot(3,3,5), imshow(closedImg), title('Morphological Closing');

    % Step 6: Use regionprops to find candidate boxes
props = regionprops(closedImg, 'BoundingBox', 'Area', 'Extent');
candidateBoxes = [];

for k = 1:length(props)
    bbox = props(k).BoundingBox;
    area = props(k).Area;
    aspectRatio = bbox(3) / bbox(4);
    extent = props(k).Extent;
    
    if area > 200 && aspectRatio > 2 && aspectRatio < 6 && extent > 0.4
        candidateBoxes = [candidateBoxes; bbox];
    end
end

if isempty(candidateBoxes)
    disp('No candidate plate regions detected.');
    return;
end

% Step 7: Select largest candidate region
maxArea = 0;
plateBoundingBox = [];
for k = 1:size(candidateBoxes, 1)
    bbox = candidateBoxes(k,:);
    currentArea = bbox(3) * bbox(4);
    if currentArea > maxArea
        maxArea = currentArea;
        plateBoundingBox = bbox;
    end
end

% Step 8: Show detected box on original image
detectedImg = insertShape(originalImg, 'Rectangle', plateBoundingBox, 'Color', 'red', 'LineWidth', 2);
subplot(3,3,6), imshow(detectedImg), title('Detected Plate Box');

% Step 9: Crop the plate
plateImg = imcrop(grayImg, plateBoundingBox);
subplot(3,3,7), imshow(plateImg), title('Cropped Plate');

% Step 10: Enhance plate for OCR
plateBW = imbinarize(plateImg, 'adaptive', 'Sensitivity', 0.45);
% plateBW = imbinarize(plateImg)
plateBW = imcomplement(plateBW);
plateBW = medfilt2(plateBW, [2, 2]);  % Smoothing filter to reduce noise
subplot(3,3,8), imshow(plateBW), title('Enhanced for OCR');

% Step 10.1: Segment characters using regionprops
CC = bwconncomp(plateBW);
stats = regionprops(CC, 'BoundingBox', 'Area');
bboxes = cat(1, stats.BoundingBox);

% Sort bounding boxes by x (horizontal) position
[~, idx] = sort(bboxes(:,1));
sortedBoxes = bboxes(idx, :);

% Filter out boxes that are too small (noise)
filteredBoxes = [];
for i = 1:size(sortedBoxes,1)
    w = sortedBoxes(i,3);
    h = sortedBoxes(i,4);
    if w > 10 && h > 20 && w < 80 && h < 100  % reasonable width/height for chars
        filteredBoxes = [filteredBoxes; sortedBoxes(i,:)];
    end
end

firstCharBySegmentation = '';
if ~isempty(filteredBoxes)
    firstCharBox = filteredBoxes(1, :);  % First box from left
    firstCharImg = imcrop(plateBW, firstCharBox);
    firstCharImg = imresize(firstCharImg, [50 50]);  % Normalize size
    firstCharImg = imcomplement(firstCharImg);  % Black text on white background
    firstCharImg = imbinarize(firstCharImg);
    
    firstCharResult = ocr(firstCharImg, ...
        'CharacterSet', 'ABCDFJKMNPRTVWZ', 'TextLayout', 'Word');
    firstCharCleaned = upper(regexprep(firstCharResult.Text, '[^A-Z]', ''));
    
    if ~isempty(firstCharCleaned)
        firstCharBySegmentation = firstCharCleaned(1);
    end
end

% === ALTERNATIVE: Region-based detection for first char (left plate side) ===
firstCharByRegion = '';
[H, W] = size(plateBW);
leftQuarter = imcrop(plateBW, [1, 1, round(W/4), H]);
leftQuarter = imcomplement(leftQuarter);  % Invert image

% Check if 'leftQuarter' is binary. If not, apply imbinarize.
if ~islogical(leftQuarter)
    leftQuarter = imbinarize(leftQuarter);  % Binarize if not already binary
end

leftQuarter = imresize(leftQuarter, [50 50]);

regionOCR = ocr(leftQuarter, ...
    'CharacterSet', 'ABCDFJKMNPRTVWZ', 'TextLayout', 'Word');
cleanRegionText = upper(regexprep(regionOCR.Text, '[^A-Z]', ''));

if ~isempty(cleanRegionText)
    firstCharByRegion = cleanRegionText(1);
end


% Step 11: OCR for the full plate
results = ocr(plateBW, ...
    'CharacterSet', '0123456789ABCDEFGHIJKLMNOPQRSTUVWXYZ', 'TextLayout', 'Block');
recognizedText = upper(regexprep(results.Text, '[\s]', ''));
disp(['Recognized Plate Text: ', recognizedText]);

% Step 12: Detect Malaysian state
stateMap = containers.Map(...
    {'A','B','C','D','F','J','K','M','N','P','R','T','V','W','Z'}, ...
    {'Perak','Selangor','Pahang','Kelantan','Putrajaya','Johor','Kedah','Melaka','Negeri Sembilan','Penang','Perlis','Terengganu','Labuan','Kuala Lumpur','Military'});

% Prioritize by: Segmented > Region > Full Plate
if ~isempty(firstCharBySegmentation)
    firstChar = firstCharBySegmentation;
elseif ~isempty(firstCharByRegion)
    firstChar = firstCharByRegion;
elseif ~isempty(recognizedText)
    firstChar = recognizedText(1);
else
    firstChar = '';
end

if ~isempty(firstChar) && isKey(stateMap, firstChar)
    state = stateMap(firstChar);
else
    state = 'Unknown';
end

% Show debug info
disp(['First Char by Segmentation: ', firstCharBySegmentation]);
disp(['First Char by Region: ', firstCharByRegion]);
disp(['Final First Character Used: ', firstChar]);
disp(['Detected State: ', state]);

app.RecognizedPlateTextEditField.Value = recognizedText;
app.DetectedStateEditField.Value = state;

% Step 13: Display final result
subplot(3,3,9), imshow(plateBW), ...
    title({['OCR: ', recognizedText], ...
    ['SegChar: ', firstCharBySegmentation], ...
    ['RegionChar: ', firstCharByRegion], ...
    ['State: ', state]});
end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            selectedName = app.ListBox.Value;

    if isempty(selectedName) || isempty(app.ImageList)
        uialert(app.UIFigure, 'Please select an image to analyze.', 'No Image Selected');
        return;
    end

    % Find the selected full image path
    for k = 1:length(app.ImageList)
        if endsWith(app.ImageList{k}, selectedName)
            selectedPath = app.ImageList{k};
            break;
        end
    end

    % Call the analysis function
    analyzeImage(app, selectedPath);
        end

        % Value changed function: VehicleTypeListBox
        function VehicleTypeListBoxValueChanged(app, event)
            selectedType = app.VehicleTypeListBox.Value;
    imageDir = fullfile(pwd, selectedType);  % assumes you're in Preprocessing/

    app.ImageList = {};
    imageNames = {};

    if exist(imageDir, 'dir')
        imageFiles = dir(fullfile(imageDir, '*.*'));
        validExtensions = {'.jpg', '.jpeg', '.png'};

        for k = 1:length(imageFiles)
            [~, ~, ext] = fileparts(imageFiles(k).name);
            if any(strcmpi(ext, validExtensions))
                fullPath = fullfile(imageFiles(k).folder, imageFiles(k).name);
                app.ImageList{end+1} = fullPath;
                imageNames{end+1} = imageFiles(k).name;
            end
        end
    end

    % Update the image filename ListBox
    app.ListBox.Items = imageNames;

    if ~isempty(imageNames)
        app.ListBox.Value = imageNames{1};  % default to first image
    else
        app.ListBox.Value = {};  % must be empty cell if no items
    end

    % Clear the displayed image
    app.Image.ImageSource = '';
            
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            selectedName = app.ListBox.Value;

    if isempty(selectedName) || isempty(app.ImageList)
        return;
    end

    % Find the matching full path
    for k = 1:length(app.ImageList)
        if endsWith(app.ImageList{k}, selectedName)
            app.Image.ImageSource = app.ImageList{k};
            break;
        end
    end  
        end

        % Value changed function: RecognizedPlateTextEditField
        function RecognizedPlateTextEditFieldValueChanged(app, event)
            value = app.RecognizedPlateTextEditField.Value;
            
        end

        % Value changed function: DetectedStateEditField
        function DetectedStateEditFieldValueChanged(app, event)
            value = app.DetectedStateEditField.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {708, 708};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {202, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AnalyzeButton = uibutton(app.LeftPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.VehicleTypeListBoxLabel = uilabel(app.LeftPanel);
            app.VehicleTypeListBoxLabel.Text = 'Vehicle Type';
            app.VehicleTypeListBox = uilistbox(app.LeftPanel);
            app.VehicleTypeListBox.Items = {'Boat', 'Truck', 'Car'};
            app.VehicleTypeListBox.ValueChangedFcn = createCallbackFcn(app, @VehicleTypeListBoxValueChanged, true);
            app.VehicleTypeListBox.Value = 'Boat';
            app.ListBoxLabel = uilabel(app.LeftPanel);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.LeftPanel);
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.RightPanel);
            app.RecognizedPlateTextEditFieldLabel = uilabel(app.RightPanel);
            app.RecognizedPlateTextEditFieldLabel.Text = 'Recognized Plate Text:';
            app.RecognizedPlateTextEditField = uieditfield(app.RightPanel, 'text');
            app.RecognizedPlateTextEditField.ValueChangedFcn = createCallbackFcn(app, @RecognizedPlateTextEditFieldValueChanged, true);
            app.RecognizedPlateTextEditField.Editable = 'off';
            app.DetectedStateEditFieldLabel = uilabel(app.RightPanel);
            app.DetectedStateEditFieldLabel.Text = 'Detected State:';
            app.DetectedStateEditField = uieditfield(app.RightPanel, 'text');
            app.DetectedStateEditField.ValueChangedFcn = createCallbackFcn(app, @DetectedStateEditFieldValueChanged, true);
            app.DetectedStateEditField.Editable = 'off';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.LeftPanel.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end