classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        ListBox                  matlab.ui.control.ListBox
        ListBoxLabel             matlab.ui.control.Label
        VehicleTypeListBox       matlab.ui.control.ListBox
        VehicleTypeListBoxLabel  matlab.ui.control.Label
        AnalyzeButton            matlab.ui.control.Button
        RightPanel               matlab.ui.container.Panel
        Image                    matlab.ui.control.Image
        ContextMenu              matlab.ui.container.ContextMenu
        Menu                     matlab.ui.container.Menu
        Menu2                    matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        ImageList
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            if ~isempty(app.ImageList)
        imgPath = app.ImageList{1};  % Change this to a random image if you want
        app.Image.ImageSource = imgPath;

        % Example of analysis logic
        % img = imread(imgPath);
        % result = analyzeImage(img);
        % disp(result);
    else
        uialert(app.UIFigure, 'No images found for selected vehicle type.', 'No Images');
    end
        end

        % Value changed function: VehicleTypeListBox
        function VehicleTypeListBoxValueChanged(app, event)
            selectedType = app.VehicleTypeListBox.Value;
    imageDir = fullfile(pwd, selectedType);  % assumes you're in Preprocessing/

    app.ImageList = {};
    imageNames = {};

    if exist(imageDir, 'dir')
        imageFiles = dir(fullfile(imageDir, '*.*'));
        validExtensions = {'.jpg', '.jpeg', '.png'};

        for k = 1:length(imageFiles)
            [~, ~, ext] = fileparts(imageFiles(k).name);
            if any(strcmpi(ext, validExtensions))
                fullPath = fullfile(imageFiles(k).folder, imageFiles(k).name);
                app.ImageList{end+1} = fullPath;
                imageNames{end+1} = imageFiles(k).name;
            end
        end
    end

    % Update the image filename ListBox
    app.ListBox.Items = imageNames;

    if ~isempty(imageNames)
        app.ListBox.Value = imageNames{1};  % default to first image
    else
        app.ListBox.Value = {};  % must be empty cell if no items
    end

    % Clear the displayed image
    app.Image.ImageSource = '';
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
selectedName = app.ListBox.Value;

    if isempty(selectedName) || isempty(app.ImageList)
        return;
    end

    % Find the matching full path
    for k = 1:length(app.ImageList)
        if endsWith(app.ImageList{k}, selectedName)
            app.Image.ImageSource = app.ImageList{k};
            break;
        end
    end       
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {708, 708};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {227, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AnalyzeButton = uibutton(app.LeftPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.VehicleTypeListBoxLabel = uilabel(app.LeftPanel);
            app.VehicleTypeListBoxLabel.Text = 'Vehicle Type';
            app.VehicleTypeListBox = uilistbox(app.LeftPanel);
            app.VehicleTypeListBox.Items = {'Boat', 'Bus', 'Car', 'Lorry', 'Motor', 'Truck'};
            app.VehicleTypeListBox.ValueChangedFcn = createCallbackFcn(app, @VehicleTypeListBoxValueChanged, true);
            app.VehicleTypeListBox.Value = 'Boat';
            app.ListBoxLabel = uilabel(app.LeftPanel);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.LeftPanel);
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.RightPanel);
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.LeftPanel.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end