classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CreatedbyWongWeiFongTP065467IanGanJianHaoTP064373Label  matlab.ui.control.Label
        RecognisedCarPlateEditField   matlab.ui.control.EditField
        VehicleStateCharacteisticsEditField  matlab.ui.control.EditField
        VehicleStateCharacteisticsEditFieldLabel  matlab.ui.control.Label
        FirstCharacterEditField       matlab.ui.control.EditField
        FirstCharacterEditFieldLabel  matlab.ui.control.Label
        RecognisedCarPlateEditFieldLabel  matlab.ui.control.Label
        LicensePlateRecognitionLPRandStateIdentificationSystemSISLabel  matlab.ui.control.Label
        CategoryButtonGroup           matlab.ui.container.ButtonGroup
        MilitaryButton                matlab.ui.control.ToggleButton
        BoatButton                    matlab.ui.control.ToggleButton
        BusButton                     matlab.ui.control.ToggleButton
        CarButton                     matlab.ui.control.ToggleButton
        LorryButton                   matlab.ui.control.ToggleButton
        TaxiButton                    matlab.ui.control.ToggleButton
        SampleDropDown                matlab.ui.control.DropDown
        AnalyzeButton                 matlab.ui.control.Button
        SampleDropDownLabel           matlab.ui.control.Label
        Image                         matlab.ui.control.Image
    end

    
    properties (Access = private)
        CurrentCategory % Description
    end
    
    methods (Access = private)
        
        function analyzeImage(app, imagePath)
            clc;
            close all;

            % Step 1: Load and display the original image
            originalImg = imread(imagePath);
            
            % Prepare a single figure window
            figure('Name','License Plate Detection Steps','NumberTitle','off');
            %subplot(3,3,1), imshow(originalImg), title('Original Image');
            figure, imshow(originalImg), title('Original Image');
            
            % Step 2: Convert to grayscale
            grayImg = rgb2gray(originalImg);
            %subplot(3,3,2), imshow(grayImg), title('Grayscale Image');
            figure, imshow(grayImg), title('Grayscale Image');
            
            % Step 3: Enhance contrast
            grayImg = imadjust(grayImg);
            %subplot(3,3,3), imshow(grayImg), title('Contrast Enhanced');
            figure, imshow(grayImg), title('Contrast Enhanced');
            
            % Step 4: Edge detection using Canny
            edges = edge(grayImg, 'Canny', [0.1 0.3]);
            %subplot(3,3,4), imshow(edges), title('Canny Edges');
            figure, imshow(edges), title('Canny Edges');
            
            % Step 5: Morphological closing
            se = strel('rectangle', [3, 15]);
            closedImg = imclose(edges, se);
            %subplot(3,3,5), imshow(closedImg), title('Morphological Closing');
            figure, imshow(closedImg), title('Morphological Closing');

            % Step 6: Use regionprops to find candidate boxes
            props = regionprops(closedImg, 'BoundingBox', 'Area', 'Extent');
            candidateBoxes = [];
            
            for k = 1:length(props)
                bbox = props(k).BoundingBox;
                area = props(k).Area;
                aspectRatio = bbox(3) / bbox(4);
                extent = props(k).Extent;
                
                if area > 200 && aspectRatio > 2 && aspectRatio < 6 && extent > 0.4
                    candidateBoxes = [candidateBoxes; bbox];
                end
            end
            
            if isempty(candidateBoxes)
                disp('No candidate plate regions detected.');
                return;
            end
            
            % Step 7: Select largest candidate region
            maxArea = 0;
            plateBoundingBox = [];
            for k = 1:size(candidateBoxes, 1)
                bbox = candidateBoxes(k,:);
                currentArea = bbox(3) * bbox(4);
                if currentArea > maxArea
                    maxArea = currentArea;
                    plateBoundingBox = bbox;
                end
            end

            % Step 8: Crop the plate
            plateImg = imcrop(grayImg, plateBoundingBox);
            %subplot(3,3,7), imshow(plateImg), title('Cropped Plate');
            figure, imshow(plateImg), title('Cropped Plate');
            
            % Step 9: Enhance plate for OCR
            %plateBW = imbinarize(plateImg, 'adaptive', 'Sensitivity', 0.45);
            plateBW = imbinarize(plateImg);
            plateBW1 = imcomplement(plateBW);
            plateBW2 = medfilt2(plateBW1, [2, 2]);
            %subplot(3,3,8), imshow(plateBW2), title('Enhanced for OCR');
            figure, imshow(plateBW2), title('Enhanced for OCR');
            
            % Step 10: Segment characters using regionprops
            CC = bwconncomp(plateBW2);
            stats = regionprops(CC, 'BoundingBox', 'Area');
            bboxes = cat(1, stats.BoundingBox);
            
            % Sort bounding boxes by x (horizontal) position
            [~, idx] = sort(bboxes(:,1));
            sortedBoxes = bboxes(idx, :);
            
            % Filter out boxes that are too small (noise)
            filteredBoxes = [];
            for i = 1:size(sortedBoxes,1)
                w = sortedBoxes(i,3);
                h = sortedBoxes(i,4);
                aspect = w/h;
                disp(['width: ', mat2str(w)]);
                disp(['height: ', mat2str(h)]);
                if w > 5 && h > 15 && w < 100 && h < 150 && aspect > 0.2 && aspect < 1.5  % reasonable width/height for chars
                    filteredBoxes = [filteredBoxes; sortedBoxes(i,:)];
                end
            end
            
            firstCharBox = [];
            firstCharBySegmentation = '';
            if ~isempty(filteredBoxes)
                firstCharBox = filteredBoxes(1, :);  % First box from left
               disp(['bounding box: ', mat2str(firstCharBox)]);
                firstCharImg = imcrop(plateBW, firstCharBox);
                firstCharImg = imresize(firstCharImg, [60 60]);  % Normalize size
                firstCharImg = im2uint8(firstCharImg);
                firstCharImg = imsharpen(firstCharImg, 'Radius', 1, 'Amount', 1.2); %sharpen image to enhance edges
                firstCharImg = imcomplement(firstCharImg);  % Black text on white background  
                if ~islogical(firstCharImg)
                    firstCharImg = imbinarize(firstCharImg);
                    %firstCharImg = imbinarize(firstCharImg, 'adaptive', 'Sensitivity', 0.45);
                end
                %firstCharImg = bwareaopen(firstCharImg, 30);
                firstCharResult = ocr(firstCharImg, ...
                    'CharacterSet', 'ABCDFHJKLMNPQRSTUVWZ', 'TextLayout', 'Word',...
                'Language','English');
                firstCharCleaned = upper(regexprep(firstCharResult.Text, '[^A-Z]', ''));
                disp(['Recognized Character: ', firstCharCleaned]);
                if ~isempty(firstCharCleaned)
                    firstCharBySegmentation = firstCharCleaned(1);
                    disp(['Recognized First Character: ', firstCharBySegmentation]);
                else
                    disp('Segmentation OCR failed to detect a valid character.');
                end            
            end
            
            % === ALTERNATIVE: Region-based detection for first char (left plate side) ===
            % firstCharByRegion = '';
            % [H, W] = size(plateBW);
            % leftQuarter = imcrop(plateBW, [1, 1, round(W/4), H]);
            % leftQuarter = imcomplement(leftQuarter);  % Invert image
            % % Check if 'leftQuarter' is binary. If not, apply imbinarize.
            % if ~islogical(leftQuarter)
            %     leftQuarter = imbinarize(leftQuarter);  % Binarize if not already binary
            % end
            % leftQuarter = imresize(leftQuarter, [50 50]);
            % regionOCR = ocr(leftQuarter, ...
            %     'CharacterSet', 'ABCDFJKMNPRTUVWZ', 'TextLayout', 'Word');
            % cleanRegionText = upper(regexprep(regionOCR.Text, '[^A-Z]', ''));
            % if ~isempty(cleanRegionText)
            %     firstCharByRegion = cleanRegionText(1);
            % end
            
            % Step 11: OCR for the full plate
            results = ocr(plateBW2, ...
                'CharacterSet', '0123456789ABCDEFGHIJKLMNOPQRSTUVWXYZ', 'TextLayout', 'Block');
            recognizedText = upper(regexprep(results.Text, '[\s]', ''));
            disp(['Recognized Plate Text: ', recognizedText]);
            
            % Step 12: Detect Malaysian state
            stateMap = containers.Map(...
                {'A','B','C','D','F','H','J','K','L','M','N','P','Q','R','S','T','U','V','W','Z'}, ...
                {'Perak','Selangor','Pahang','Kelantan','Putrajaya','Taxi','Johor','Kedah','Labuan','Melaka','Negeri Sembilan','Penang','Sarawak','Perlis','Sabah','Terengganu','iM4U Sentral','Kuala Lumpur','Kuala Lumpur','Military'});
            
            % Prioritize by: Segmented > Region > Full Plate
            if ~isempty(firstCharBySegmentation)                           %recognise first character
                firstChar = firstCharBySegmentation;                       
            %elseif ~isempty(firstCharByRegion)                             %take first character of first half plate
            %    firstChar = firstCharByRegion;
            %if ~isempty(recognizedText)
            %   firstChar = recognizedText(1);                             %full text's first character
            else
                firstChar = 'NONE';
            end
            
            if ~isempty(firstChar) && isKey(stateMap, firstChar)
                state = stateMap(firstChar);
            else
                state = 'Unknown';
            end
            
            % Show debug info
            disp(['First Char by Segmentation: ', firstCharBySegmentation]);
            %disp(['First Char by Region: ', firstCharByRegion]);
            disp(['Final First Character Used: ', firstChar]);
            disp(['Detected State: ', state]);

            % === Insert red plate + green first character box ===
            shapes = plateBoundingBox;
            colors = {'red'};
            if ~isempty(firstCharBox)
                % Convert firstCharBox to original image coords
                adjustedFirstCharBox = firstCharBox;
                adjustedFirstCharBox(1) = adjustedFirstCharBox(1) + plateBoundingBox(1);
                adjustedFirstCharBox(2) = adjustedFirstCharBox(2) + plateBoundingBox(2);
                shapes = [shapes; adjustedFirstCharBox];
                colors = [colors, {'green'}];
            end
            detectedImg = insertShape(originalImg, 'rectangle', shapes, 'Color', colors, 'LineWidth', 2);
            %subplot(3,3,6), imshow(detectedImg), title('Detected Plate Box');
            figure, imshow(detectedImg), title('Detected Plate Box');

            app.RecognisedCarPlateEditField.Value = recognizedText;
            app.FirstCharacterEditField.Value = firstChar;
            app.VehicleStateCharacteisticsEditField.Value = state;
            
            % Step 13: Display final result
            %subplot(3,3,9), 
            figure, imshow(plateBW2), ...
                title({['OCR: ', recognizedText], ...
                ['RegionChar: ', firstChar], ...
                ['State: ', state]});
                    end
                end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Image.ScaleMethod = 'fit';
            app.CurrentCategory = app.CategoryButtonGroup.SelectedObject.Text;
            CategoryButtonGroupSelectionChanged(app, []);  % Simulate selection to load images
        end

        % Callback function: AnalyzeButton, FirstCharacterEditField, 
        % ...and 2 other components
        function AnalyzeButtonPushed(app, event)
            selectedFile = app.SampleDropDown.Value;
            selectedCategory = app.CurrentCategory;
        
            %baseImagePath = 'C:\IPPR\matlab\Preprocessing';  % Adjust as needed
            baseImagePath = fullfile(fileparts(mfilename('fullpath')), 'Preprocessing');
            imagePath = fullfile(baseImagePath, selectedCategory, selectedFile);
        
            if isfile(imagePath)
                analyzeImage(app, imagePath);
            else
                uialert(app.UIFigure, 'Selected image not found.', 'Error');
            end
        end

        % Value changed function: SampleDropDown
        function SampleDropDownValueChanged(app, event)
            selectedFile = app.SampleDropDown.Value;
            selectedCategory = app.CurrentCategory;
        
            %baseImagePath = 'C:\IPPR\matlab\Preprocessing';  % Match your folder path
            baseImagePath = fullfile(fileparts(mfilename('fullpath')), 'Preprocessing');
            imagePath = fullfile(baseImagePath, selectedCategory, selectedFile);
        
            if isfile(imagePath)
                img = imread(imagePath);    
                app.Image.ImageSource = img;  % Shows image in uiimage
            else
                uialert(app.UIFigure, 'Image file not found.', 'Error');
            end
        end

        % Selection changed function: CategoryButtonGroup
        function CategoryButtonGroupSelectionChanged(app, event)
            
            selectedButton = app.CategoryButtonGroup.SelectedObject.Text;
            app.CurrentCategory = selectedButton;
            
            %baseImagePath = 'C:\IPPR\matlab\Preprocessing';  % example
            baseImagePath = fullfile(fileparts(mfilename('fullpath')), 'Preprocessing');
            categoryFolder = fullfile(baseImagePath, selectedButton);
            
            disp(['Looking in: ', categoryFolder]);  % Debug print


            if isfolder(categoryFolder)
                imageFiles =    [dir(fullfile(categoryFolder, '*.jpg')); ...
                                dir(fullfile(categoryFolder, '*.png'))];
                fileNames = {imageFiles.name};  % Extract file names
        
                if ~isempty(fileNames)
                    app.SampleDropDown.Items = fileNames;
                    app.SampleDropDown.Value = fileNames{1};  % Select first item by default
                    SampleDropDownValueChanged(app, []);

                    % Auto-preview first image
                    imagePath = fullfile(categoryFolder, fileNames{1});
                    img = imread(imagePath);
                    app.Image.ImageSource = img;
                else
                    app.SampleDropDown.Items = {'<No images found>'};
                    app.Image.ImageSource = [];  % Clear image
                end
            else
                app.SampleDropDown.Items = {'<Invalid folder>'};
                app.Image.ImageSource = [];  % Clear image
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Boat', 'boat-AWR7120.jpg');
            app.SampleDropDownLabel = uilabel(app.UIFigure);
            app.SampleDropDownLabel.Text = 'Sample';
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.SampleDropDown = uidropdown(app.UIFigure);
            app.SampleDropDown.Items = {'boat-TKS00114P.jpg'};
            app.SampleDropDown.ValueChangedFcn = createCallbackFcn(app, @SampleDropDownValueChanged, true);
            app.SampleDropDown.Value = 'boat-TKS00114P.jpg';
            app.CategoryButtonGroup = uibuttongroup(app.UIFigure);
            app.CategoryButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CategoryButtonGroupSelectionChanged, true);
            app.CategoryButtonGroup.Title = 'Category';
            app.TaxiButton = uitogglebutton(app.CategoryButtonGroup);
            app.TaxiButton.Text = 'Taxi';
            app.LorryButton = uitogglebutton(app.CategoryButtonGroup);
            app.LorryButton.Text = 'Lorry';
            app.CarButton = uitogglebutton(app.CategoryButtonGroup);
            app.CarButton.Text = 'Car';
            app.BusButton = uitogglebutton(app.CategoryButtonGroup);
            app.BusButton.Text = 'Bus';
            app.BoatButton = uitogglebutton(app.CategoryButtonGroup);
            app.BoatButton.Text = 'Boat';
            app.BoatButton.Value = true;
            app.MilitaryButton = uitogglebutton(app.CategoryButtonGroup);
            app.MilitaryButton.Text = 'Military';
            app.LicensePlateRecognitionLPRandStateIdentificationSystemSISLabel = uilabel(app.UIFigure);
            app.LicensePlateRecognitionLPRandStateIdentificationSystemSISLabel.Text = 'License Plate Recognition (LPR) and State Identification System (SIS)';
            app.RecognisedCarPlateEditFieldLabel = uilabel(app.UIFigure);
            app.RecognisedCarPlateEditFieldLabel.Text = 'Recognised Car Plate';
            app.FirstCharacterEditFieldLabel = uilabel(app.UIFigure);
            app.FirstCharacterEditFieldLabel.Text = 'First Character';
            app.FirstCharacterEditField = uieditfield(app.UIFigure, 'text');
            app.FirstCharacterEditField.ValueChangedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.FirstCharacterEditField.Editable = 'off';
            app.FirstCharacterEditField.Enable = 'off';
            app.VehicleStateCharacteisticsEditFieldLabel = uilabel(app.UIFigure);
            app.VehicleStateCharacteisticsEditFieldLabel.Text = 'Vehicle State/Characteistics';
            app.VehicleStateCharacteisticsEditField = uieditfield(app.UIFigure, 'text');
            app.VehicleStateCharacteisticsEditField.ValueChangedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.VehicleStateCharacteisticsEditField.Editable = 'off';
            app.VehicleStateCharacteisticsEditField.Enable = 'off';
            app.RecognisedCarPlateEditField = uieditfield(app.UIFigure, 'text');
            app.RecognisedCarPlateEditField.ValueChangedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.RecognisedCarPlateEditField.Editable = 'off';
            app.RecognisedCarPlateEditField.Enable = 'off';
            app.CreatedbyWongWeiFongTP065467IanGanJianHaoTP064373Label = uilabel(app.UIFigure);
            app.CreatedbyWongWeiFongTP065467IanGanJianHaoTP064373Label.Text = 'Created by Wong Wei Fong (TP065467) & Ian Gan Jian Hao (TP064373)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end